classdef link_excel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        UITable               matlab.ui.control.Table
        ReadDataButton        matlab.ui.control.Button
        UIAxes                matlab.ui.control.UIAxes
        PlotgraphButton       matlab.ui.control.Button
        UIAxes2               matlab.ui.control.UIAxes
        SinusoidalplotButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        t % Description tabel to be used in two callbacks
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
%             x=0:0.1:1*(360/180)*pi;
%             y=sin(x);
%             plot(app.UIAxes2,x,y);
        end

        % Button pushed function: ReadDataButton
        function ReadDataButtonPushed(app, event)
            app.t=readtable("link_excel.xlsx","Sheet",1);
            app.UITable.Data=app.t;
            app.t.Properties.VariableNames{1}='X_Data';
            app.t.Properties.VariableNames{2}='Y_Data';
            app.UITable.ColumnName=app.t.Properties.VariableNames;
            
        end

        % Button pushed function: PlotgraphButton
        function PlotgraphButtonPushed(app, event)
            x=table2array(app.t(:,"X_Data"));
            y=table2array(app.t(:,"Y_Data"));
            plot(app.UIAxes,x,y);
        end

        % Button pushed function: SinusoidalplotButton
        function SinusoidalplotButtonPushed(app, event)
            x=0:0.1:1*(360/180)*pi;
            y=sin(x);
            plot(app.UIAxes2,x,y);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'};
            app.UITable.RowName = {};
            app.ReadDataButton = uibutton(app.UIFigure, 'push');
            app.ReadDataButton.ButtonPushedFcn = createCallbackFcn(app, @ReadDataButtonPushed, true);
            app.ReadDataButton.Text = {'Read Data'; ''};
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.PlotgraphButton = uibutton(app.UIFigure, 'push');
            app.PlotgraphButton.ButtonPushedFcn = createCallbackFcn(app, @PlotgraphButtonPushed, true);
            app.PlotgraphButton.Text = {'Plot graph '; ''};
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.SinusoidalplotButton = uibutton(app.UIFigure, 'push');
            app.SinusoidalplotButton.ButtonPushedFcn = createCallbackFcn(app, @SinusoidalplotButtonPushed, true);
            app.SinusoidalplotButton.Text = 'Sinusoidal plot ';
        end
    end

    methods (Access = public)

        % Construct app
        function app = link_excel

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end